classdef mouse_reach < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        NextEventButton                 matlab.ui.control.Button
        PreviousEventButton             matlab.ui.control.Button
        Slider                          matlab.ui.control.Slider
        SliderButtonRev                 matlab.ui.control.Button
        SliderButtonFw                  matlab.ui.control.Button
        YesButton_Success               matlab.ui.control.Button
        NoButton_Success                matlab.ui.control.Button
        SuccessLabel                    matlab.ui.control.Label
        GrabbingEventsLabel             matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        CopyrightLabel                  matlab.ui.control.Label
        SaveTableButton                 matlab.ui.control.Button
        FramesLabel                     matlab.ui.control.Label
        MouseReachAppLabel              matlab.ui.control.Label
        CurrentEventLabel               matlab.ui.control.Label
        TableLabel                      matlab.ui.control.Label
        LoadTableButton                 matlab.ui.control.Button
        DisplayTrajectoriesButtonGroup  matlab.ui.container.ButtonGroup
        ShowButton                      matlab.ui.control.ToggleButton
        HideButton                      matlab.ui.control.ToggleButton
        RemainingEventsButton           matlab.ui.control.Button
        NextVideoButton                 matlab.ui.control.Button
        PreviousVideoButton             matlab.ui.control.Button
        VideosLabel                     matlab.ui.control.Label
        RemoveEventButton               matlab.ui.control.Button
        DisplayPelletsButtonGroup       matlab.ui.container.ButtonGroup
        Box1Button                      matlab.ui.control.ToggleButton
        Box2Button                      matlab.ui.control.ToggleButton
        Box3Button                      matlab.ui.control.ToggleButton
        Box4Button                      matlab.ui.control.ToggleButton
        AutomaticButton                 matlab.ui.control.ToggleButton
        AddEventButton                  matlab.ui.control.StateButton
        VideoEditFieldLabel             matlab.ui.control.Label
        VideoEditField                  matlab.ui.control.EditField
        GlobalTimeEditFieldLabel        matlab.ui.control.Label
        GlobalTimeEditField             matlab.ui.control.EditField
        GrabLabel                       matlab.ui.control.Label
        YesButton_Grab                  matlab.ui.control.Button
        NoButton_Grab                   matlab.ui.control.Button
        TongueButton                    matlab.ui.control.Button
        SlipButton                      matlab.ui.control.Button
        SlipsLabel                      matlab.ui.control.Label
        NextSlipButton                  matlab.ui.control.Button
        PreviousSlipButton              matlab.ui.control.Button
    end

    
    properties (Access = private) %values that are share globally with app.property
        table_path %global app values
        big_table
        grabbing_event %just a table row index
        video
        current_image
        csv
        savecheck
        filepath %for next/previous video buttons
        video_name %as well
        grabbing_events
        coords %for adding new events
        pellet_file %for checking pellet status
        cdown_tab %for plotting style
        pellet_coords_fixed %for displaying pellet status
        disp_box %remember the choice of previous pellet display box
        addevent_pushed %addevent button not pushed/pushed
        all_events %all possible events for the video (reach+grab)
        current_event %current_event out of all_events (reach+grab)
        change_video %decide whether to load new or keep the old video
        video_start
        video_end
        video_current
        slipping_event
        slipping_events
    end
    
    methods (Access = private)
        
        %additional functions that have no buttons
        
        %slider functions for keypress (with bigger slider step = 10)
        function SliderBigRev(app, event)
            if app.Slider.Value >= app.Slider.Limits(1)+10 %if bigger than lower limit+step, move backwards
                app.Slider.Value = app.Slider.Value - 10; %steps of 10
                SliderValueChanged(app, event);
            end
        end
        
        function SliderBigFw(app, event)
            if app.Slider.Value <= app.Slider.Limits(2)-10 %if smaller than upper limit+step, move forward
                app.Slider.Value = app.Slider.Value + 10;
                SliderValueChanged(app, event);
            end
        end
        
        %my main functions used in multiple callbacks
        
        %load video function
        function app = LoadVideo(app)
            video_loc = strrep(app.filepath, 'Tracking_csvs/merger/', ''); %PT
%             video_loc = strrep(app.filepath, 'Analyzed_csvs/merger/', ''); %METAMIZOLE
            vname = strcat(app.video_name, '.avi'); disp(vname)
            event = app.current_event; table = app.big_table;
            
            if app.change_video == 1 %if new video, load (eats memory)
                video_loc = VideoReader(char(strcat(video_loc,'/',vname))); %add next video name out of folder
                if ~isempty(app.video), app.video = []; end
                try
                    app.video = read(video_loc); %update global video
                    app.video_start = 1;
                    app.video_end = app.video_start+size(app.video,4)-2;
                    app.video_current = table.Frame(event);
                catch
                    app.video_start = max(1,app.big_table.Frame(app.current_event));
                    app.video_end = min(app.big_table.Frame(app.current_event)+2000,video_loc.NumFrames);
                    app.video = read(video_loc,[app.video_start app.video_end]); %if too big, reduce size
                    app.video_current = abs(table.Frame(event)-app.video_start+1); %video timeline shifts because of different starting point
                end
            else
                app.video_current = table.Frame(event);
            end
            
            if ~isempty(event) %if event, plot frame at current event
                output_image = imadjust(app.video(:,:,:,app.video_current),[0 0.8],[0 1]); %capture image and brighten it up
            else %otherwise, plot first frame
                output_image = imadjust(app.video(:,:,:,1),[0 0.8],[0 1]); %if no event, plot at FramesStart
            end
            
            app.current_image = output_image; %save image for replotting
            image(app.UIAxes, output_image); %plot image
            
            hold(app.UIAxes,'on'); plot(app.UIAxes, table.x(event), table.y(event),'gx','MarkerSize',15);
            hold(app.UIAxes,'off'); %plot grab event
            
            app.VideoEditField.Value = char(app.video_name); %load videoname on screen
            try
                app.GlobalTimeEditField.Value = ReadTime(output_image); %load time
            catch
                disp('Could not read time!')
            end
            
        end
        
        %adjust slider (adjust timeline)
        function app = AdjustSlider(app)
            table = app.big_table;
            
            FramesStart = app.video_start; FramesEnd = app.video_end; %set the starting point and end of the timeline (csv = video-1)
            app.Slider.Limits = [FramesStart FramesEnd]; %select timeline labeling limits and step
            
            if FramesEnd >= 6000, tick_step = floor(FramesEnd/6000)*600; elseif FramesEnd >= 1000, tick_step = floor(FramesEnd/1000)*100; ...
            elseif FramesEnd >= 100, tick_step = floor(FramesEnd/100)*10; elseif FramesEnd >= 10, tick_step = floor(FramesEnd/10); else, tick_step = 1; end
        
        disp(FramesStart); disp(app.video_start)
        
        %set up graphical slider components (timeline step)
        app.Slider.MajorTickLabels = cellstr(string(FramesStart:tick_step-1:FramesEnd)); %timeline labels
        app.Slider.MajorTicks = FramesStart:tick_step:FramesEnd;
        
        
        %move slider to event
        if ~isempty(app.current_event)
            app.Slider.Value = table.Frame(app.current_event);
        else
            app.Slider.Value = FramesStart;
        end
        end
        
        %load csv table of the video
        function [app, csv_file] = LoadCsv(app, table, grab_event)
            csv_file = readtable(char(strcat(table.Folder(grab_event),'/',table.Video(grab_event),'.csv'))); %find video csv file
            csv_file = csv_file(strcmp(csv_file.bodyparts,'Hand') & csv_file.likelihood > 0.999,:); %extract hand coords for all boxes
            app.csv = csv_file; %save globally & csv_file.box == table.Box(grab_event)
        end
        
        %plot trajectories
        function PlotTrajectory(app, csv_file)
            
            traj_button = app.DisplayTrajectoriesButtonGroup.SelectedObject.Text; %check trajectory button
            if strcmp(traj_button, 'Show')
                
                hold(app.UIAxes,'on');
                plot(app.UIAxes, csv_file.x(csv_file.frames==app.Slider.Value),...
                    csv_file.y(csv_file.frames==app.Slider.Value),'.r','MarkerSize',20);
                for box = 1:4 %run through all boxes separately so lines don't merge
                    plot(app.UIAxes, csv_file.x(csv_file.frames > max(app.Slider.Value-20, min(csv_file.frames))...
                        & csv_file.frames < min(app.Slider.Value+20, max(csv_file.frames)) & csv_file.box == box),...
                        csv_file.y(csv_file.frames > max(app.Slider.Value-20, min(csv_file.frames)) & ...
                        csv_file.frames < min(app.Slider.Value+20, max(csv_file.frames)) & csv_file.box == box),'-r','LineWidth',1);
                end
                hold(app.UIAxes,'off');
                
            end
        end
        
        %draw table
        function app = DrawTable(app)
            table = app.big_table; grab_events = app.grabbing_events; %grab_event = app.grabbing_event;
            events = app.all_events; event = app.current_event;
            slip_events = app.slipping_events; %slip_event = app.sliping_event;
            
            if isempty(events), app.UITable.Data = {}; return; end %if no grabbing event, empty the table
            
            uitable_plholder = cell([length(events) 6]);
            for n = 1:length(events) %otherwise, display all grabbing events
                uitable_plholder(n,:) = [table.Video(events(n)) table.Box(events(n))...
                    table.Frame(events(n)) table.Pellet(events(n)) table.Grab(events(n)) table.Success(events(n))];
            end
            app.UITable.Data = uitable_plholder; %show data in table
            
            style = uistyle('BackgroundColor','white','FontWeight','normal'); addStyle(app.UITable,style); %refresh table color
            
            %mark current event in bold
            if ~isempty(event) %color only the current grabbing event
                [to_color,~] = find(event == events);
                style = uistyle('FontWeight','bold'); addStyle(app.UITable,style,'row',to_color);
            end
            
            %color all grabbing events
            if ~isempty(grab_events)
                [to_color,~] = find(ismember(events,grab_events));
                if ~isempty(to_color)
                    style = uistyle('BackgroundColor','yellow'); addStyle(app.UITable,style,'row',to_color); %matt green [0.64 0.83 0.75]
                end
                
            end
            
            %color all slips
            if ~isempty(slip_events)
                [to_color,~] = find(ismember(events,slip_events));
                if ~isempty(to_color)
                    style = uistyle('BackgroundColor','green'); addStyle(app.UITable,style,'row',to_color);
                end
            end
            
            %scroll to event
            %scroll function, introduced first in 2021a
        end
        
        %initiate pellets data
        function app = InitiatePellets(app)
            %load pellet file
            pellet_loc = app.filepath; %PT
%             pellet_loc = strrep(app.filepath, 'Analyzed_csvs', 'Pellet_Tracking_csvs'); %METAMIZOLE
            pellet_loc = strcat(pellet_loc, '/', app.video_name, '.csv');
            app.pellet_file = readtable(char(pellet_loc)); pellet_csv = app.pellet_file;
            
            %preallocate static pellet matrix
            pellet_coords = zeros(8,3,4); pellet_coords(1:8,1,1:4) = repmat([1;2;3;4;5;6;7;8],1,4);
            cup = zeros(4,2); cdown = zeros(4,2); cdown_t = zeros(4,1);
            corr_value = 10; %+corr_value because length of pellet pocket is ca. 5 pixels away from corner
            
            for box = 1:4
                %calculate average upper and lower corner vars
                cup_index = find(strcmp(pellet_csv.bodyparts,'CornerUp') & pellet_csv.likelihood == 1 & pellet_csv.box == box);
                cup(box,:) = [mean(pellet_csv.x(cup_index)), mean(pellet_csv.y(cup_index))];
                cdown_index = find(strcmp(pellet_csv.bodyparts,'CornerDown') & pellet_csv.likelihood == 1 & pellet_csv.box == box);
                cdown(box,:) = [mean(pellet_csv.x(cdown_index)), mean(pellet_csv.y(cdown_index))];
                cdown_t(box) = cdown(box,2)+20; %used to position markers in a tab on screen
                
                %determine static pellet coords to display a pellet that has low probability
                if cup(box,1) > cdown(box,1) %determine orientation of box; if xup > xdown
                    pellet_coords(1,2,box) = cup(box,1)-corr_value; pellet_coords(1,3,box) = cup(box,2);
                    pellet_coords(8,2,box) = cdown(box,1)+corr_value; pellet_coords(8,3,box) = cdown(box,2);
                elseif cup(box,1) < cdown(box,1)
                    pellet_coords(1,2,box) = cup(box,1)+corr_value; pellet_coords(1,3,box) = cup(box,2);
                    pellet_coords(8,2,box) = cdown(box,1)-corr_value; pellet_coords(8,3,box) = cdown(box,2);
                end
            end
            
            pellet_coords(pellet_coords == 0) = NaN;
            pellet_coords = fillmissing(pellet_coords, 'linear');
            app.cdown_tab = cdown_t; app.pellet_coords_fixed = pellet_coords;
        end
        
        %plot pellet status (green/red)
        function PelletStatus(app)
            pellet_csv = app.pellet_file; cdown_t = app.cdown_tab; pellet_coords = app.pellet_coords_fixed;
            
            cdown_t = [repmat(cdown_t(1),8,1) repmat(cdown_t(2),8,1) repmat(cdown_t(3),8,1) repmat(cdown_t(4),8,1)]; %reshapes cdown_t into 8x2 matrix (for plotting)
            
            %check user input for box choice
            dispellets_button = app.DisplayPelletsButtonGroup.SelectedObject.Text;
            switch true
                case strcmp(dispellets_button,'Box 1')
                    box = 1;
                case strcmp(dispellets_button,'Box 2')
                    box = 2;
                case strcmp(dispellets_button,'Box 3')
                    box = 3;
                case strcmp(dispellets_button,'Box 4')
                    box = 4;
                case strcmp(dispellets_button,'Automatic')
                    if ~isempty(app.grabbing_event)
                        box = app.big_table.Box(app.grabbing_event);
                    elseif isempty(app.grabbing_event) && ~isempty(app.disp_box)
                        box = app.disp_box; %retain previous box choice
                    else
                        box = 1; %default position
                    end
            end
            app.disp_box = box; %remember the choice of box in case event gets removed
            
            %pellet tracking in real time
            for pellet = 1:8
                pellet_name = strcat('Pellet',string(pellet));
                pellet_index = find(strcmp(pellet_csv.bodyparts,pellet_name) & pellet_csv.frames == app.Slider.Value...
                    & pellet_csv.box == box & pellet_csv.likelihood > 0.999);
                
                if ~isempty(pellet_index)
                    pellet_coords(pellet,2,box) = pellet_csv.x(pellet_index); pellet_coords(pellet,3,box) = pellet_csv.y(pellet_index);
                    pellet_coords(pellet,4,box) = 1; %set 4th column value to 1 = green light
                else, pellet_coords(pellet,4,box) = 0; %red light
                end
            end
            
            [grpell_idx,~] = find(pellet_coords(:,4,box) == 1); [redpell_idx,~] = find(pellet_coords(:,4,box) ~= 1);
            
            hold(app.UIAxes,'on'); plot(app.UIAxes,pellet_coords(grpell_idx,2,box),cdown_t(grpell_idx,box),'ks','MarkerSize',10,'MarkerFaceColor','green');
            plot(app.UIAxes,pellet_coords(redpell_idx,2,box),cdown_t(redpell_idx,box),'ks','MarkerSize',10,'MarkerFaceColor','red'); hold(app.UIAxes,'off');
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LoadTableButton.Tooltip = 'Load table produced by Mouse Compute.';
            app.SaveTableButton.Tooltip = 'Save table and continue with further analysis.';
            app.NextEventButton.Tooltip = 'Go to next grabbing event. "E"';
            app.PreviousEventButton.Tooltip = 'Go to previous grabbing event. "W"';
            app.RemainingEventsButton.Tooltip = 'Find first grabbing event that has not been categorized yet.';
            app.Slider.Tooltip = 'Slider corresponds to the video timeline.';
            app.YesButton_Success.Tooltip = 'The pellet was eaten. "X"';
            app.NoButton_Success.Tooltip = 'The pellet was dropped. "C"';
            app.CurrentEventLabel.Tooltip = 'Variables corresponding to the current grabbing event.';
            app.DisplayTrajectoriesButtonGroup.Tooltip = 'Activate or deactivate trajectories.';
            app.SliderButtonRev.Tooltip = 'Move slider forwards. "Rightarrow = +1; D = +10"';
            app.SliderButtonFw.Tooltip = 'Move slider backwards. "Leftarrow = -1; S = -10"';
            
            app.UIAxes.Toolbar.Visible = 'off'; %disable axis upper toolbar
            app.AddEventButton.Value = 0; %disable ButtonDown Fcn until AddEvent button is pressed
            
            
        end

        % Button pushed function: NextEventButton
        function NextEventButtonPushed(app, event)
            %check if table is loaded
            if  isempty(app.table_path) %if table not already loaded
                uialert(app.UIFigure,'Please load the table first!', 'Warning');
                return;
            end
            
            %create local values
            table = app.big_table; %local variable to work with
            grab_event = app.grabbing_event; %local variable for grabbing_event
            grab_events = app.grabbing_events; %local variable for grabbing_events
            
            %create a list of grabbing events
            if isempty(grab_event) %if no previous event
                
                if isempty(app.video_name) %if video have been opened yet, start from the beginning of table
                    grab_event = find(table.Grab == 1,1); %find next grabbing event
                    grab_events = find(table.Grab == 1 & strcmp(table.Video(grab_event), table.Video)...
                        & strcmp(table.Folder(grab_event), table.Folder)); %see if there are more events in same video
                    
                else %if we have an open video without grabbing events
                    vidname = app.video_name; vidname = char(vidname); csv_loc = app.filepath;
                    vidnum = str2double(vidname(3:end)); vidcam = vidname(1); %calculate variables for finding the video spot in table
                    tabmat = cell2mat(table.Video); tabnum = str2num(tabmat(:,3:end));  %#ok<ST2NM>
                    
                    row_start = find(tabnum >= vidnum & strcmp(csv_loc, table.Folder) & table.Camera == 1,1); %find index matching A_*
                    row_start = [row_start; find(tabnum >= vidnum & strcmp(csv_loc, table.Folder) & table.Camera == 2,1)]; %find index matching B_*
                    
                    if ~isempty(row_start) %if we can't find any grabbing event in the session, move to next grab event in the big table
                        
                        if strcmp(vidcam, 'A') && length(row_start) == 2 %if our video is A_* and row_start has two variables
                            row_start = min(row_start(1), row_start(2)); %choose the smaller of two variables
                        elseif strcmp(vidcam, 'B') && length(row_start) == 2 %if our video is B_*
                            row_start = max(row_start(1), row_start(2)); %choose the bigger
                        end %why? because if A, we need the closer parameter, and if B, we need the farther parameter; filepath sets the upper limit
                        
                        grab_event = row_start + find(table.Grab(row_start+1:end) == 1,1); %find first next grab event
                        grab_events = [grab_event; grab_event + find(table.Grab(grab_event+1:end) == 1 &... %find all events for this video
                            strcmp(table.Video(grab_event), table.Video(grab_event+1:end)) &...
                            strcmp(table.Folder(grab_event), table.Folder(grab_event+1:end)))];
                        
                    else
                        row_start = find(strcmp(app.filepath, table.Folder),1,'last'); %go to last video from this dataset in big table
                        grab_event = row_start + find(table.Grab(row_start+1:end) == 1,1); %and then search for grabs
                        grab_events = [grab_event; grab_event + find(table.Grab(grab_event+1:end) == 1 &...
                            strcmp(table.Video(grab_event), table.Video(grab_event+1:end)) &...
                            strcmp(table.Folder(grab_event), table.Folder(grab_event+1:end)))];
                    end
                end
                
            else %search for new event
                grab_event = grab_event + find(table.Grab(grab_event+1:end) == 1,1);
                
                if ~ismember(grab_event, grab_events) %if grab_event does not exist within grab_events, create new list
                    grab_events = [grab_event; grab_event + find(table.Grab(grab_event+1:end) == 1 &...
                        strcmp(table.Video(grab_event), table.Video(grab_event+1:end)) &...
                        strcmp(table.Folder(grab_event), table.Folder(grab_event+1:end)))];
                end
            end
            
            if isempty(grab_event), uialert(app.UIFigure,'There are no grabbing events remaining!', 'Warning'); return; end %if last event
            
            %find reaches based on grab_events
            app.current_event = grab_event;
            app.all_events = find(~isnan(table.Grab) & strcmp(table.Video(grab_event), table.Video) & strcmp(table.Folder(grab_event), table.Folder));
            
            %update global variables
            app.grabbing_event = grab_event; app.grabbing_events = grab_events;
            if strcmp(app.filepath, table.Folder(grab_event)) && strcmp(app.video_name, table.Video(grab_event))...
                    && app.video_start == 1 %if video_start ~= 1, video is chopped due to large size, load next segment
                app.change_video = 0; %if same video, do not load again later
            else
                app.filepath = table.Folder(grab_event); app.video_name = table.Video(grab_event);
                app.change_video = 1; %if new video, load anew
            end
            
            %load video
            LoadVideo(app)
            app.change_video = 1; %baseline value
            
            %adjust slider (timeline)
            AdjustSlider(app)
            
            %display table values
            DrawTable(app);
            
            %load csv table of the video
            [app, csv_file] = LoadCsv(app, table, grab_event);
            
            %plot trajectories
            PlotTrajectory(app, csv_file)
            
            %plot pellets
            app = InitiatePellets(app);
            PelletStatus(app)
            
            %turn savecheck on
            app.savecheck = 0; %if 0, prompt before exiting the program
        end

        % Button pushed function: PreviousEventButton
        function PreviousEventButtonPushed(app, event)
            %everything same as in previous button except find function
            
            if  isempty(app.table_path)
                uialert(app.UIFigure,'Please load the table first!', 'Warning');
                return;
            end
            
            %create local values
            table = app.big_table; grab_event = app.grabbing_event; grab_events = app.grabbing_events;
            
            %create a list of grabbing events
            if isempty(grab_event)
                
                if isempty(app.video_name)
                    uialert(app.UIFigure,'No previous grabbing events!', 'Warning'); return;
                    
                else
                    vidname = app.video_name; vidname = char(vidname); csv_loc = app.filepath;
                    vidnum = str2double(vidname(3:end)); vidcam = vidname(1); %calculate variables for finding the video spot in table
                    tabmat = cell2mat(table.Video); tabnum = str2num(tabmat(:,3:end));  %#ok<ST2NM>
                    
                    row_start = find(tabnum <= vidnum & strcmp(csv_loc, table.Folder) & table.Camera == 1,1,'last');
                    row_start = [row_start; find(tabnum <= vidnum & strcmp(csv_loc, table.Folder) & table.Camera == 2,1,'last')];
                    
                    if ~isempty(row_start)
                        
                        if strcmp(vidcam, 'A') && length(row_start) == 2
                            row_start = min(row_start(1), row_start(2));
                        elseif strcmp(vidcam, 'B') && length(row_start) == 2
                            row_start = max(row_start(1), row_start(2));
                        end %same thing applies as in nextbutton
                        
                        grab_event = find(table.Grab(1:row_start) == 1,1,'last'); %consider only grab events from current video backwards
                        grab_events = [find(table.Grab(1:grab_event-1) == 1 &...
                            strcmp(table.Video(grab_event), table.Video(1:grab_event-1)) &...
                            strcmp(table.Folder(grab_event), table.Folder(1:grab_event-1))); grab_event];
                    else
                        row_start = find(strcmp(app.filepath, table.Folder),1);
                        grab_event = find(table.Grab(1:row_start-1) == 1,1,'last');
                        grab_events = [find(table.Grab(1:grab_event-1) == 1 &...
                            strcmp(table.Video(grab_event), table.Video(1:grab_event-1)) &...
                            strcmp(table.Folder(grab_event), table.Folder(1:grab_event-1))); grab_event];
                    end
                end
                
            else
                grab_event = find(table.Grab(1:grab_event-1) == 1,1,'last');
                
                if ~ismember(grab_event, grab_events) %if grab_event does not exist within grab_events, create new list
                    grab_events = [find(table.Grab(1:grab_event-1) == 1 &...
                        strcmp(table.Video(grab_event), table.Video(1:grab_event-1)) &...
                        strcmp(table.Folder(grab_event), table.Folder(1:grab_event-1))); grab_event];
                end
            end
            
            if isempty(grab_event), uialert(app.UIFigure,'No previous grabbing events!', 'Warning'); return; end
            
            %find reaches based on grab_events
            app.current_event = grab_event;
            app.all_events = find(~isnan(table.Grab) & strcmp(table.Video(grab_event), table.Video) & strcmp(table.Folder(grab_event), table.Folder));
            
            %update global variables
            app.grabbing_event = grab_event; app.grabbing_events = grab_events;
            if strcmp(app.filepath, table.Folder(grab_event)) && strcmp(app.video_name, table.Video(grab_event))...
                    && app.video_start == 1
                app.change_video = 0; %if same video, do not load again later
            else
                app.filepath = table.Folder(grab_event); app.video_name = table.Video(grab_event);
                app.change_video = 1; %if new video, load anew
            end
            
            %load video
            LoadVideo(app)
            app.change_video = 1; %baseline value
            
            %adjust slider (timeline)
            AdjustSlider(app)
            
            %display table values
            DrawTable(app);
            
            %load csv table of the video
            [app, csv_file] = LoadCsv(app, table, grab_event);
            
            %plot trajectories
            PlotTrajectory(app, csv_file)
            
            %plot pellets
            app = InitiatePellets(app);
            PelletStatus(app)
            
            %turn savecheck on
            app.savecheck = 0;
        end

        % Button pushed function: RemainingEventsButton
        function RemainingEventsButtonPushed(app, event)
            %everything same as in previous button except find function
            
            if  isempty(app.table_path)
                uialert(app.UIFigure,'Please load the table first!', 'Warning');
                return;
            end
            
            %create local values
            table = app.big_table; %other values unnecessary
            
            %create a list of grabbing events
            grab_event = find(table.Grab == 1 & isnan(table.Success),1); %find first event with NaN value
            
            if isempty(grab_event), uialert(app.UIFigure,'All grabbing events have been evaluated!', 'Warning'); return; end
            
            grab_events = find(table.Grab == 1 & strcmp(table.Video(grab_event), table.Video)...
                & strcmp(table.Folder(grab_event), table.Folder)); %find all events for that video
            
            %find reaches based on grab_events
            app.current_event = grab_event;
            app.all_events = find(~isnan(table.Grab) & strcmp(table.Video(grab_event), table.Video) & strcmp(table.Folder(grab_event), table.Folder));
            
            %update global variables
            app.grabbing_event = grab_event; app.grabbing_events = grab_events;
            app.filepath = table.Folder(grab_event); app.video_name = table.Video(grab_event);
            
            %load video
            LoadVideo(app)
            
            %adjust slider (timeline)
            AdjustSlider(app)
            
            %display table values
            DrawTable(app);
            
            %load csv table of the video
            [app, csv_file] = LoadCsv(app, table, grab_event);
            
            %plot trajectories
            PlotTrajectory(app, csv_file)
            
            %plot pellets
            app = InitiatePellets(app);
            PelletStatus(app)
            
            %turn savecheck on
            app.savecheck = 0;
        end

        % Button pushed function: NextVideoButton
        function NextVideoButtonPushed(app, event)
            
            if  isempty(app.table_path)
                uialert(app.UIFigure,'Please load the table first!', 'Warning');
                return;
            end
            
            %create local values
            table = app.big_table; grab_event = app.grabbing_event;
            
            if ~isempty(grab_event) %if we are starting from a grabbing event/prior usage were Event buttons
                
                %load csv/video path starting from current event
                csv_loc = table.Folder(grab_event); %extract csv loc out of big table
                video_loc = strrep(table.Folder(grab_event), 'Tracking_csvs/merger/', ''); %edit csv location to video location %PT
%                 video_loc = strrep(table.Folder(grab_event), 'Analyzed_csvs/merger/', ''); %METAMIZOLE
                
                %list all the csv/video files
                csv_files = {dir([char(csv_loc),'/*.csv']).name}; %list of csv files
                video_files = {dir([char(video_loc),'/*.avi']).name}; %this is a cell list of all video names
                
                %find the position of the current csv/video in the folder
                csv_placing = find(strcmp(strcat(app.video_name, '.csv'), csv_files));
                video_placing = find(strcmp(strcat(app.video_name,'.avi'), video_files));
                
                %what if *_placing empty? !!!!!!!IMPORTANT
                
                if csv_placing == size(csv_files, 2), uialert(app.UIFigure,'Last .csv in session!', 'Warning'); return; end
                if video_placing == size(video_files, 2), uialert(app.UIFigure,'Last video in session!', 'Warning'); return; end %if not last video in folder
                vidname = char(video_files(video_placing+1)); vidname = vidname(1:end-4); %vidname without avi, local variable
                
                %check in big table if grabbing events for this particular video exist
                grab_events = find(table.Grab == 1 & strcmp(vidname, table.Video) & strcmp(csv_loc, table.Folder));
                if ~isempty(grab_events), grab_event = grab_events(1); else, grab_event = []; end
                
                %find reaches based on grab_events
                if ~isempty(grab_event)
                    app.current_event = grab_event;
                    app.all_events = find(~isnan(table.Grab) & strcmp(table.Video(grab_event), table.Video) & strcmp(table.Folder(grab_event), table.Folder));
                else %just take the first event on the list
                    app.all_events = find(~isnan(table.Grab) & strcmp(vidname, table.Video) & strcmp(csv_loc, table.Folder));
                    if ~isempty(app.all_events), app.current_event = app.all_events(1); else, app.current_event = []; end
                end
                
                %update global variables
                app.grabbing_event = grab_event; app.grabbing_events = grab_events; app.filepath = csv_loc; app.video_name = vidname;
                
                %load video
                LoadVideo(app)
                
                %adjust slider (timeline)
                AdjustSlider(app)
                
                %display table values
                DrawTable(app)
                if ~isempty(grab_event), app.grabbing_event = grab_event; else, app.grabbing_event = []; end %turn list into integer
                
                %load csv table of the video
                cname = strcat(app.video_name, '.csv');
                csv_file = readtable(char(strcat(csv_loc,'/', cname))); %find video csv file
                csv_file = csv_file(strcmp(csv_file.bodyparts,'Hand') & csv_file.likelihood > 0.999,:); %extract hand coords (BOX OUT HERE)
                app.csv = csv_file; %save globally
                
                %plot trajectories
                PlotTrajectory(app, csv_file)
                
                %plot pellets
                app = InitiatePellets(app);
                PelletStatus(app)
                
            elseif isempty(grab_event) && ~isempty(app.filepath) %if we are starting from a video not in the table
                csv_loc = app.filepath;
                video_loc = strrep(csv_loc, 'Tracking_csvs/merger/', ''); %PT
%                 video_loc = strrep(csv_loc, 'Analyzed_csvs/merger/', ''); %METAMIZOLE
                
                %list all the csv/video files
                csv_files = {dir([char(csv_loc),'/*.csv']).name};
                video_files = {dir([char(video_loc),'/*.avi']).name};
                
                %find the position of the current csv/video in the folder
                csv_placing = find(strcmp(strcat(app.video_name, '.csv'), csv_files));
                video_placing = find(strcmp(strcat(app.video_name,'.avi'), video_files));
                
                if csv_placing == size(csv_files, 2), uialert(app.UIFigure,'Last .csv in session!', 'Warning'); return; end
                if video_placing == size(video_files, 2), uialert(app.UIFigure,'Last video in session!', 'Warning'); return; end
                vidname = char(video_files(video_placing+1)); vidname = vidname(1:end-4);
                
                %check for grabbing events in this video (future)
                grab_events = find(table.Grab == 1 & strcmp(vidname, table.Video) & strcmp(csv_loc, table.Folder)); %both finds at the moment 1
                if ~isempty(grab_events), grab_event = grab_events(1); else, grab_event = []; end
                
                %find reaches based on grab_events
                if ~isempty(grab_event)
                    app.current_event = grab_event;
                    app.all_events = find(~isnan(table.Grab) & strcmp(table.Video(grab_event), table.Video) & strcmp(table.Folder(grab_event), table.Folder));
                else %just take the first event on the list
                    app.all_events = find(~isnan(table.Grab) & strcmp(vidname, table.Video) & strcmp(csv_loc, table.Folder));
                    if ~isempty(app.all_events), app.current_event = app.all_events(1); else, app.current_event = []; end
                end
                
                %update global variables
                app.grabbing_event = grab_event; app.grabbing_events = grab_events; app.filepath = csv_loc; app.video_name = vidname;
                
                %load video
                LoadVideo(app)
                
                %adjust slider (timeline)
                AdjustSlider(app)
                
                %display table values
                DrawTable(app)
                if ~isempty(grab_event), app.grabbing_event = grab_event; else, app.grabbing_event = []; end %turn list into integer
                
                %load csv table of the video
                cname = strcat(app.video_name, '.csv');
                csv_file = readtable(char(strcat(csv_loc,'/', cname))); %find video csv file
                csv_file = csv_file(strcmp(csv_file.bodyparts,'Hand') & csv_file.likelihood > 0.999,:); %extract hand coords (BOX OUT HERE)
                app.csv = csv_file; %save globally
                
                %plot trajectories
                PlotTrajectory(app, csv_file)
                
                %plot pellets
                app = InitiatePellets(app);
                PelletStatus(app)
                
            end
        end

        % Button pushed function: PreviousVideoButton
        function PreviousVideoButtonPushed(app, event)
            %same except for vidname = videoplacing-1 and csv_placing/video_placing uialerts
            
            if  isempty(app.table_path)
                uialert(app.UIFigure,'Please load the table first!', 'Warning');
                return;
            end
            
            %create local values
            table = app.big_table; grab_event = app.grabbing_event;
            
            if ~isempty(grab_event) %if we are starting from a grabbing event/prior usage were Event buttons
                
                %load csv/video path starting from current event
                csv_loc = table.Folder(grab_event); %extract csv loc out of big table
                video_loc = strrep(table.Folder(grab_event), 'Tracking_csvs/merger/', ''); %edit csv location to video location %PT
%                 video_loc = strrep(table.Folder(grab_event), 'Analyzed_csvs/merger/', ''); %METAMIZOLE
                
                %list all the csv/video files
                csv_files = {dir([char(csv_loc),'/*.csv']).name}; %list of csv files
                video_files = {dir([char(video_loc),'/*.avi']).name}; %this is a cell list of all video names
                
                %find the position of the current csv/video in the folder
                csv_placing = find(strcmp(strcat(app.video_name, '.csv'), csv_files));
                video_placing = find(strcmp(strcat(app.video_name,'.avi'), video_files));
                
                if csv_placing == 1, uialert(app.UIFigure,'First .csv file in session!', 'Warning'); return; end
                if video_placing == 1, uialert(app.UIFigure,'First video file in session!', 'Warning'); return; end %if not last video in folder
                vidname = char(video_files(video_placing-1)); vidname = vidname(1:end-4); %vidname without avi, local variable
                
                %check in big table if grabbing events for this particular video exist
                grab_events = find(table.Grab == 1 & strcmp(vidname, table.Video) & strcmp(csv_loc, table.Folder));
                if ~isempty(grab_events), grab_event = grab_events(1); else, grab_event = []; end
                
                %find reaches based on grab_events
                if ~isempty(grab_event)
                    app.current_event = grab_event;
                    app.all_events = find(~isnan(table.Grab) & strcmp(table.Video(grab_event), table.Video) & strcmp(table.Folder(grab_event), table.Folder));
                else %just take the first event on the list
                    app.all_events = find(~isnan(table.Grab) & strcmp(vidname, table.Video) & strcmp(csv_loc, table.Folder));
                    if ~isempty(app.all_events), app.current_event = app.all_events(1); else, app.current_event = []; end
                end
                
                %update global variables
                app.grabbing_event = grab_event; app.grabbing_events = grab_events; app.filepath = csv_loc; app.video_name = vidname;
                
                %load video
                LoadVideo(app)
                
                %adjust slider (timeline)
                AdjustSlider(app)
                
                %display table values
                DrawTable(app)
                if ~isempty(grab_event), app.grabbing_event = grab_event; else, app.grabbing_event = []; end %turn list into integer
                
                %load csv table of the video
                cname = strcat(app.video_name, '.csv');
                csv_file = readtable(char(strcat(csv_loc,'/', cname))); %find video csv file
                csv_file = csv_file(strcmp(csv_file.bodyparts,'Hand') & csv_file.likelihood > 0.999,:); %extract hand coords (BOX OUT HERE)
                app.csv = csv_file; %save globally
                
                %plot trajectories
                PlotTrajectory(app, csv_file)
                
                %plot pellets
                app = InitiatePellets(app);
                PelletStatus(app)
                
            elseif isempty(grab_event) && ~isempty(app.filepath) %if we are starting from a video not in the table
                csv_loc = app.filepath;
                video_loc = strrep(table.Folder(grab_event), 'Tracking_csvs/merger/', ''); %PT
%                 video_loc = strrep(csv_loc, 'Analyzed_csvs/merger/', ''); %METAMIZOLE
                
                %list all the csv/video files
                csv_files = {dir([char(csv_loc),'/*.csv']).name};
                video_files = {dir([char(video_loc),'/*.avi']).name};
                
                %find the position of the current csv/video in the folder
                csv_placing = find(strcmp(strcat(app.video_name, '.csv'), csv_files));
                video_placing = find(strcmp(strcat(app.video_name,'.avi'), video_files));
                
                if csv_placing == 1, uialert(app.UIFigure,'First .csv file in session!', 'Warning'); return; end
                if video_placing == 1, uialert(app.UIFigure,'First video file in session!', 'Warning'); return; end
                vidname = char(video_files(video_placing-1)); vidname = vidname(1:end-4);
                
                %check for grabbing events in this video (future)
                grab_events = find(table.Grab == 1 & strcmp(vidname, table.Video) & strcmp(csv_loc, table.Folder));
                if ~isempty(grab_events), grab_event = grab_events(1); else, grab_event = []; end
                
                %find reaches based on grab_events
                if ~isempty(grab_event)
                    app.current_event = grab_event;
                    app.all_events = find(~isnan(table.Grab) & strcmp(table.Video(grab_event), table.Video) & strcmp(table.Folder(grab_event), table.Folder));
                else %just take the first event on the list
                    app.all_events = find(~isnan(table.Grab) & strcmp(vidname, table.Video) & strcmp(csv_loc, table.Folder));
                    if ~isempty(app.all_events), app.current_event = app.all_events(1); else, app.current_event = []; end
                end
                
                %update global variables
                app.grabbing_event = grab_event; app.grabbing_events = grab_events; app.filepath = csv_loc; app.video_name = vidname;
                
                %load video
                LoadVideo(app)
                
                %adjust slider (timeline)
                AdjustSlider(app)
                
                %display table values
                DrawTable(app)
                if ~isempty(grab_event), app.grabbing_event = grab_event; else, app.grabbing_event = []; end %turn list into integer
                
                %load csv table of the video
                cname = strcat(app.video_name, '.csv');
                csv_file = readtable(char(strcat(csv_loc,'/', cname))); %find video csv file
                csv_file = csv_file(strcmp(csv_file.bodyparts,'Hand') & csv_file.likelihood > 0.999,:); %extract hand coords (BOX OUT HERE)
                app.csv = csv_file; %save globally
                
                %plot trajectories
                PlotTrajectory(app, csv_file)
                
                %plot pellets
                app = InitiatePellets(app);
                PelletStatus(app)
                
            end
        end

        % Button pushed function: YesButton_Grab
        function YesButton_GrabPushed(app, event)
            table = app.big_table; grab_events = app.grabbing_events; event = app.current_event;
            
            if isempty(event), return; end %return if event empty
            
            table.Grab(event) = 1; %change value in Excel table
            
            if ~ismember(event,grab_events) %enlist among grab events
                idx = find(grab_events > event,1);
                
                if ~isempty(idx) && idx > 1, grab_events = [grab_events(1:idx-1); event; grab_events(idx:end)];
                elseif ~isempty(idx) && idx == 1, grab_events = [event; grab_events];
                else, grab_events = [grab_events; event];
                end
                
                app.grabbing_event = event; app.grabbing_events = grab_events;
            end
            
            app.big_table = table; %update big_table
            DrawTable(app); %redraw table
            
            %turn savecheck on
            app.savecheck = 0;
        end

        % Button pushed function: NoButton_Grab
        function NoButton_GrabPushed(app, event)
            table = app.big_table; grab_events = app.grabbing_events; event = app.current_event;
            
            if isempty(event), return; end %return if event empty
            
            table.Grab(event) = 0; %change value in Excel table
            
            if ismember(event,grab_events)
                table.Success(event) = NaN; %also set Success to NaN
                grab_events = grab_events(grab_events ~= event); %expel event out of grabbing events
                app.grabbing_event = []; app.grabbing_events = grab_events;
            end
            
            app.big_table = table; %update big_table
            DrawTable(app); %redraw table
            
            %turn savecheck on
            app.savecheck = 0;
        end

        % Button pushed function: TongueButton
        function TonguePushed(app, event)
            table = app.big_table; grab_events = app.grabbing_events; event = app.current_event;
            
            if isempty(event), return; end %return if event empty
            
            table.Grab(event) = 1; table.Success(event) = 2; %Tongue equals Grab = 1, Success = 2
            
            if ~ismember(event,grab_events) %enlist among grab events
                idx = find(grab_events > event,1);
                
                if ~isempty(idx) && idx > 1, grab_events = [grab_events(1:idx-1); event; grab_events(idx:end)];
                elseif ~isempty(idx) && idx == 1, grab_events = [event; grab_events];
                else, grab_events = [grab_events; event];
                end
                
                app.grabbing_event = event; app.grabbing_events = grab_events;
            end
            
            app.big_table = table; %update big_table
            DrawTable(app); %redraw table
            
            %turn savecheck on
            app.savecheck = 0;
        end

        % Button pushed function: SlipButton
        function SlipPushed(app, event)
            table = app.big_table; grab_events = app.grabbing_events; event = app.current_event;
            
            if isempty(event), return; end %return if event empty
            
            table.Grab(event) = 2; %slips Grab = 2
            
            if ismember(event,grab_events)
                table.Success(event) = NaN; %also set Success to NaN
                grab_events = grab_events(grab_events ~= event); %expel event out of grabbing events
                app.grabbing_event = []; app.grabbing_events = grab_events;
            end
            
            app.big_table = table; %update big_table
            DrawTable(app); %redraw table
            
            %turn savecheck on
            app.savecheck = 0;
        end

        % Button pushed function: YesButton_Success
        function YesButton_SuccessPushed(app, event)
            table = app.big_table; grab_events = app.grabbing_events; event = app.current_event;
            
            if isempty(event), return; %return if event empty or if grab event not selected
            elseif ~ismember(event,grab_events), uialert(app.UIFigure,'Not a grabbing event!', 'Warning'); return;
            end
            
            table.Success(event) = 1; %change value in Excel table
            
            app.big_table = table; %update big_table
            DrawTable(app); %redraw table
            
            %turn savecheck on
            app.savecheck = 0;
        end

        % Button pushed function: NoButton_Success
        function NoButton_SuccessPushed(app, event)
            table = app.big_table; grab_events = app.grabbing_events; event = app.current_event;
            
            if isempty(event), return; %return if event empty or if grab event not selected
            elseif ~ismember(event,grab_events), uialert(app.UIFigure,'Not a grabbing event!', 'Warning'); return;
            end
            
            table.Success(event) = 0;
            
            app.big_table = table; %update big_table
            DrawTable(app);
            
            %turn savecheck on
            app.savecheck = 0;
        end

        % Button pushed function: LoadTableButton
        function LoadTableButtonPushed(app, event)
            %load table
            if  isempty(app.table_path) %if table not already loaded
                app.table_path = '/home/nikolaus/Desktop/Matlab_Scripts/PT_Toolkit/MATLAB_Scripts/big_merge_matrix_PT_slips_20.xlsx'; %PT
%                 app.table_path = '/home/nikolaus/Desktop/Matlab_Scripts/PT_Toolkit/MATLAB_Scripts/big_merge_matrix_M_slips_20.xlsx'; %METAMIZOLE _Metamizole.xlsx
                %                 [filename,pathname] = uigetfile('*.xlsx'); %prompt user to load the file
                %                 app.table_path = strcat(pathname,filename);
                app.big_table = readtable(app.table_path);
                
                msg_load = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Loading Table');
                msg_load.Value = 0.33; pause(0.5); msg_load.Value = 0.66;
                NextEventButtonPushed(app, event); %load first event automatically
                msg_load.Value = 1; pause(0.5); close(msg_load);
                
            else
                uialert(app.UIFigure,'Table already loaded!', 'Warning');
            end
        end

        % Button pushed function: SaveTableButton
        function SaveTableButtonPushed(app, event)
            if  isempty(app.table_path), uialert(app.UIFigure,'Please load the table first!', 'Warning'); return; end %table loaded?
            
            choice = uiconfirm(app.UIFigure, 'Do you really want to save the table?','Save Table',...
                'Options',{'Overwrite','Save as new','Cancel'}, 'DefaultOption',1,'CancelOption',3); %create choice dialog box
            
            switch choice %3 choices how to save the table
                case 'Overwrite'
                    msg_save = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Saving Table'); %progress dialogue
                    msg_save.Value = 0.33; pause(0.3); msg_save.Value = 0.5; %replace down below with app.table_path + save original?
                    writetable(app.big_table, app.table_path);
                    msg_save.Value = 1; pause(0.3); close(msg_save);
                    
                    app.savecheck = 1; %can exit without prompting the user to save
                case 'Save as new'% open save new dialog box, output name and path
                    [filename, pathname] = uiputfile('big_table_PT_app.xlsx'); %PT
                    %[filename, pathname] = uiputfile('big_table_Metamizole_app.xlsx'); %METAMIZOLE
                    if ~all(filename) || ~all(pathname), return; end %if these variables are zero, return
                    
                    msg_save = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Saving Table');
                    msg_save.Value = 0.33; pause(0.3); msg_save.Value = 0.5;
                    writetable(app.big_table, strcat(pathname,filename));
                    msg_save.Value = 1; pause(0.3); close(msg_save);
                    
                    app.savecheck = 1; %can exit without prompting the user to save
                case 'Cancel'
                    return;
            end
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            if isempty(app.video), return; end %if no video, do not move the slider
            
            %adjust video position
            app.Slider.Value = round(app.Slider.Value); %check new position & convert to discrete value
            
            if app.video_start == 1
                new_timepoint = app.Slider.Value;
            else
                new_timepoint = app.Slider.Value-app.video_start+1;
            end
            
            output_image = imadjust(app.video(:,:,:,new_timepoint),[0 0.8],[0 1]); %find image equal to new slider value
            image(app.UIAxes, output_image);
            try
                app.GlobalTimeEditField.Value = ReadTime(output_image); %display video time
            catch
                disp('Could not read time!')
            end
            
            
            %matej test 22/08/2022, better solution, but does not work on Ubuntu
            %             video_loc = strrep(app.filepath, 'Analyzed_csvs/merger/', ''); vname = strcat(app.video_name, '.avi');
            %             video_loc = VideoReader(char(strcat(video_loc,'/',vname))); %add next video name out of folder
            %             video_loc.CurrentTime = app.Slider.Value*0.04; %one frame corresponds to 0.04s => 40 ms (one frame is every 10s, but video is slowed down 4x)
            %             video_frame = readFrame(video_loc);
            %             output_image = video_frame; %output_image = imadjust(video_frame,[0 0.8],[0 1]);
            %             image(app.UIAxes, output_image);
            %             app.GlobalTimeEditField.Value = ReadTime(output_image);
            
            
            %change and plot grabbing attempts as slider value changes
            table = app.big_table; grab_event = app.grabbing_event; grab_events = app.grabbing_events;
            events = app.all_events; event = app.current_event;
            
            if ~isempty(events) && any(app.Slider.Value == table.Frame(events)) %if slider value matches any events
                
                if ~isequal(app.Slider.Value,table.Frame(event)) %if slider position matches a new event on the list or if event empty
                    change_event = find(app.Slider.Value == table.Frame(events),1);
                    event = events(change_event);
                    
                    %if the event is also a grabbing event
                    if event == grab_events, grab_event = event; app.grabbing_event = grab_event; end
                    
                    app.grabbing_event = grab_event; app.current_event = event; %update global values
                    DrawTable(app); %update table values
                end
                
                hold(app.UIAxes,'on'); %plot current grab event
                plot(app.UIAxes, table.x(event), table.y(event),'gx','MarkerSize',15);
                hold(app.UIAxes,'off');
            end
            
            %plot trajectories
            csv_file = app.csv; %csv already loaded into app.csv
            PlotTrajectory(app, csv_file)
            
            %plot pellet status
            PelletStatus(app)
            
            %save current image
            app.current_image = output_image;
        end

        % Button pushed function: SliderButtonRev
        function SliderButtonRevPushed(app, event)
            if app.Slider.Value > app.Slider.Limits(1) %if bigger than lower limit, move backwards
                app.Slider.Value = app.Slider.Value - 1;
                SliderValueChanged(app, event);
            end
        end

        % Button pushed function: SliderButtonFw
        function SliderButtonFwPushed(app, event)
            if app.Slider.Value < app.Slider.Limits(2) %if smaller than upper limit, move forward
                app.Slider.Value = app.Slider.Value + 1;
                SliderValueChanged(app, event);
            end
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key; %when right or left arrows are pressed
            switch key
                case 'rightarrow' %toggle the slider buttons
                    SliderButtonFwPushed(app, event);
                case 'leftarrow'
                    SliderButtonRevPushed(app, event);
                case 'x'
                    YesButton_SuccessPushed(app, event);
                case 'c'
                    NoButton_SuccessPushed(app, event);
                case 'e'
                    NextEventButtonPushed(app, event);
                case 'w'
                    PreviousEventButtonPushed(app, event);
                case 's'
                    SliderBigRev(app, event); %move slider for a greater range, two functions below properties
                case 'd'
                    SliderBigFw(app, event);
            end
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            %verticalScrollAmount = event.VerticalScrollAmount; %equals 3; unnecessary
            verticalScrollCount = event.VerticalScrollCount;
            switch verticalScrollCount %move slider by scrolling
                case 1 %if scrolled down
                    SliderButtonFwPushed(app, event);
                case -1 %if scrolled up
                    SliderButtonRevPushed(app, event);
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            if isempty(app.big_table), return; end
            indices = event.Indices; %position of the edited cell
            newData = event.NewData; %content of the edited cell
            table = app.big_table;
            grab_event = app.grabbing_event;
            switch indices(2) %check which column was edited and write data to table accordingly
                case 1, table.Video(grab_event) = newData;
                case 2, table.Box(grab_event) = newData;
                case 3, table.Frame(grab_event) = newData;
                case 4, table.Pellet(grab_event) = newData;
                case 5, table.Success(grab_event) = newData;
            end
            app.big_table = table;
            
        end

        % Selection changed function: DisplayTrajectoriesButtonGroup
        function TrajectoriesButtonSelectionChanged(app, event)
            %traj_button = app.DisplayTrajectoriesButtonGroup.SelectedObject.Text; %check trajectory button/used in PlotTrajectories
            SliderValueChanged(app, event); %reload slider and show/hide trajectories
        end

        % Selection changed function: DisplayPelletsButtonGroup
        function DisplayPelletsButtonSelectionChanged(app, event)
            %dispellets_button = app.DisplayPelletsButtonGroup.SelectedObject.Text; %used in PelletStatus function
            SliderValueChanged(app, event); %reload slider and replot
        end

        % Value changed function: AddEventButton
        function AddEventButtonValueChanged(app, event)
            %value = app.AddEventButton.Value;
            
            if isempty(app.big_table)
                uialert(app.UIFigure,'Please load the table first!', 'Warning');
                app.AddEventButton.Value = 0; return;
            end
            
            %wait for ButtonDown
            while isempty(app.coords) && app.AddEventButton.Value == 1
                pause(1);
            end
            
            %if AddEvent was turned off in the meantime (user doesn't want to add grab)
            if app.AddEventButton.Value == 0; return; end
            
            %load values
            table = app.big_table; vidname = app.video_name; csv_loc = app.filepath;
            
            %find a suitable position in the big table
            vidname = char(vidname); vidnum = str2double(vidname(3:end)); vidcam = vidname(1);
            tabmat = cell2mat(table.Video); tabnum = str2num(tabmat(:,3:end));  %#ok<ST2NM>
            
            row_start = find(tabnum <= vidnum & strcmp(csv_loc, table.Folder) & table.Camera == 1,1,'last');
            row_start = [row_start; find(tabnum <= vidnum & strcmp(csv_loc, table.Folder) & table.Camera == 2,1,'last')];
            
            if ~isempty(row_start)
                if strcmp(vidcam, 'A') && length(row_start) == 2
                    row_start = min(row_start(1), row_start(2));
                elseif strcmp(vidcam, 'B') && length(row_start) == 2
                    row_start = max(row_start(1), row_start(2));
                end
                %else -> stays the way it is
            else %if empty, meaning no previous videos, start at the beginning of category A/B
                if strcmp(vidcam,'A')
                    row_start = find(strcmp(csv_loc, table.Folder) & table.Camera == 1,1);
                elseif strcmp(vidcam,'B')
                    row_start = find(strcmp(csv_loc, table.Folder) & table.Camera == 2,1);
                end
            end
            
            %determine current hand & box
            x = app.coords(1); y = app.coords(2);
            switch true
                case x < 320 && y < 240 %leftup
                    hand = 'R'; box = 1; %disp(hand); disp(box);
                case x > 320 && y < 240 %rightup
                    hand = 'L'; box = 3; %disp(hand); disp(box);
                case x < 320 && y > 240 %leftdown
                    hand = 'R'; box = 2; %disp(hand); disp(box);
                case x > 320 && y > 240 %rightdown
                    hand = 'L'; box = 4; %disp(hand); disp(box);
            end
            
            %ask user for pellet removal
            choice = uiconfirm(app.UIFigure,'Was the pellet removed?','Pellet Removal','Options',{'Yes','No','Cancel'},...
                'DefaultOption',1,'CancelOption',3);
            if strcmp(choice,'Yes')
                pellet_removal = 1; uialert(app.UIFigure,'Please evaluate the grab type and success!', 'Hand or tongue?','Icon','Info');
            elseif strcmp(choice,'No')
                pellet_removal = 0; uialert(app.UIFigure,'Please evaluate the movement type!', 'Reach or slip?','Icon','Info');
            elseif strcmp(choice,'Cancel')
                app.AddEventButton.Value = 0; app.coords = []; SliderValueChanged(app, event); return; %reset
            end
            
            %determine pellet number
            pellet_coords = app.pellet_coords_fixed;
            [~,closest_pellet] = min(abs(pellet_coords(:,2,box)-x)); %look at closest x coordinate for that box
            pellet = pellet_coords(closest_pellet,1,box); %determine number of closest pellet
            
            
            %%%%METAMIZOLE
            %determine mouse (boxes are constant - clockwise 1 3 4 2; mice change)
%                         if strcmp(vidcam,'A')
%                             cam = 1; %set camera to 1
%                             switch table.Subgroup(row_start)
%                                 case 1
%                                     switch box, case 1, mouse = 1; case 2, mouse = 4; case 3, mouse = 2; case 4, mouse = 3; end
%                                 case 2
%                                     switch box, case 1, mouse = 5; case 2, mouse = 8; case 3, mouse = 6; case 4, mouse = 7; end
%                                 case 3
%                                     switch box, case 1, mouse = 9; case 2, mouse = 12; case 3, mouse = 10; case 4, mouse = 11; end
%                             end %different nomenclature in provided dataset and my boxes
%                         elseif strcmp(vidcam,'B')
%                             cam = 2; %set camera to 2
%                             switch table.Subgroup(row_start)
%                                 case 1
%                                     switch box, case 3, mouse = 1; case 4, mouse = 4; case 1, mouse = 2; case 2, mouse = 3; end
%                                 case 2
%                                     switch box, case 3, mouse = 5; case 4, mouse = 8; case 1, mouse = 6; case 2, mouse = 7; end
%                                 case 3
%                                     switch box, case 3, mouse = 9; case 4, mouse = 12; case 1, mouse = 10; case 2, mouse = 11; end
%                             end
%                         end
%             
%             %find experimental group in the experimental table (provided by the experimenter)
%             %             [filename,pathname] = uigetfile('*.xlsx'); % prompt user to load the file (alternative
%             %             exp_table = strcat(pathname,filename);
%             
%             %METAMIZOLE (3 rows)
%                         exp_table = readtable('/mnt/66E0A3E3E0A3B827/Matej_Metamizol/Staircase_exemplary_table.xlsx');
%                         [exp_group_index,~] = find(exp_table.Day == table.Day(row_start) & exp_table.Group == table.Group(row_start)...
%                             & exp_table.Subgroup == table.Subgroup(row_start) & exp_table.Label == mouse);
%                         exp_group = exp_table.Treatment(exp_group_index);
            
            %PT
            exp_table = readtable('/mnt/66E0A3E3E0A3B827/Photothrombosis_Feb_2022/Experimental_table_PT.xlsx'); %PT
            if strcmp(vidcam,'A') %PT
                cam = 1;
                switch box
                    case 1, cage = 1;
                    case 2, cage = 4;
                    case 3, cage = 2;
                    case 4, cage = 3;
                end
            elseif strcmp(vidcam,'B')
                cam = 2;
                switch box
                    case 1, cage = 2;
                    case 2, cage = 3;
                    case 3, cage = 1;
                    case 4, cage = 4;
                end
            end
            
            mouse_idx = find(exp_table.Day == table.Day(row_start) & exp_table.Group == table.Group(row_start)...
                & exp_table.Subgroup == table.Subgroup(row_start) & exp_table.Cage == cage,1);
            mouse = exp_table.ID(mouse_idx); exp_group = exp_table.Treatment(mouse_idx);
            
            %create future new row
            new_event = table(1,:); %preallocate variable types
            new_event.Video(1) = {vidname}; new_event.Frame(1) = app.Slider.Value; new_event.x(1) = x;
            new_event.y(1) = y; new_event.Grab(1) = pellet_removal; new_event.Pellet(1) = pellet;
            new_event.Success(1) = NaN; new_event.Hand(1) = {hand}; new_event.Box(1) = box; new_event.Camera(1) = cam;
            new_event.Trial(1) = exp_group; new_event.Mouse(1) = mouse; new_event.Subgroup(1) = table.Subgroup(row_start);
            new_event.Group(1) = table.Group(row_start); new_event.Day(1) = table.Day(row_start); new_event.Folder(1) = csv_loc;
            %{'Video','Frame','x','y','Grab','Pellet','Success','Hand','Box','Camera','Trial','Mouse','Subgroup','Group','Day','Folder'}
            
            %check if the video has reaching attempts and position the new row accordingly; "row_insert"
            row_insert = find(strcmp(vidname,table.Video) & strcmp(csv_loc,table.Folder) & table.Frame <= new_event.Frame,1,'last');
            if isempty(row_insert) %if no previous events of this video
                table = [table(1:row_start-1,:); new_event; table(row_start:end,:)]; row_idx = row_start;
            else
                table = [table(1:row_insert,:); new_event; table(row_insert+1:end,:)]; row_idx = row_insert+1;
            end
            app.big_table = table;
            
            %add to all events list
            event = row_idx; app.current_event = event;
            app.all_events = find(~isnan(table.Grab) & strcmp(table.Video(event), table.Video) & strcmp(table.Folder(event), table.Folder));
            
            %add/show new grabbing event
            if new_event.Grab == 1
                grab_event = row_idx; %the position of my new grab event
            elseif new_event.Grab == 0
                grab_event = app.current_event; %remember old grabbing event position bc of next/previous event buttons
            end
            
            %update grabbing events list due to new event order
            grab_events = find(table.Grab == 1 & strcmp(table.Video(event), table.Video) & strcmp(table.Folder(event), table.Folder));
            app.grabbing_event = grab_event; app.grabbing_events = grab_events;
            
            %replot image
            SliderValueChanged(app, event)
            
            %reset app table
            DrawTable(app)
            
            %revert button to normal state
            app.AddEventButton.Value = 0;
            app.coords = []; %empty coords; they are not needed anymore
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            while app.AddEventButton.Value ~= 1, return; end %if AddEvent button not pushed, return
            
            cpoint = app.UIAxes.CurrentPoint; %current point; returns 2x3 array of points
            x = cpoint(1,1); y = cpoint(1,2); %extract (x,y) coordinates
            
            if x > 640 || x < 0 || y < 0 || y > 480, uialert(app.UIFigure,'Out of bounds!', 'Warning'); return; end
            
            app.coords = [x y]; %global coords
            
            image(app.UIAxes, app.current_image); %replot image
            csv_file = app.csv; PlotTrajectory(app, csv_file) %replot trajectory
            
            hold(app.UIAxes,'on'); plot(app.UIAxes,x,y,'ro'); hold(app.UIAxes,'off'); %plot new click location
            
        end

        % Button pushed function: RemoveEventButton
        function RemoveEventButtonPushed(app, event)
            if isempty(app.big_table), uialert(app.UIFigure,'Please load the table first!', 'Warning'); end
            
            event = app.current_event; events = app.all_events; grab_event = app.grabbing_event;
            grab_events = app.grabbing_events; table = app.big_table;
            
            if isempty(event), uialert(app.UIFigure, 'Please choose a grabbing event first.', 'Warning'); return; end
            
            table.Grab(event) = NaN; %mark Grab as invalid = 2
            
            %if event grab, remove grab event from grab events
            if isequal(event,grab_event)
                grab_events = grab_events(grab_events ~= grab_event); grab_event = [];
            end
            
            %remove event from all events
            events = events(events ~= event); event = [];
            
            app.current_event = event; app.all_events = events; app.grabbing_event = grab_event;
            app.grabbing_events = grab_events; app.big_table = table; %update global values
            
            %update picture
            SliderValueChanged(app, event)
            DrawTable(app)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.savecheck == 0
                exit = uiconfirm(app.UIFigure,'Do you really want to exit without saving?', 'Close Mouse Reach');
                if ~strcmp(exit,'OK'), return; end %if something was modified and not saved, check before exiting
            end
            delete(app)
            
        end

        % Button pushed function: NextSlipButton
        function NextSlipButtonPushed(app, event)
            %adapted from NextEvent (grab_event replaced with slip_event)
            
            %check if table is loaded
            if  isempty(app.table_path) %if table not already loaded
                uialert(app.UIFigure,'Please load the table first!', 'Warning');
                return;
            end
            
            %create local values
            table = app.big_table; %local variable to work with
            slip_event = app.slipping_event; %local variable for grabbing_event
            slip_events = app.slipping_events; %local variable for grabbing_events
            
            %create a list of grabbing events
            if isempty(slip_event) %if no previous event
                
                if isempty(app.video_name) %if video have been opened yet, start from the beginning of table
                    slip_event = find(table.Grab == 2,1); %find next grabbing event
                    slip_events = find(table.Grab == 2 & strcmp(table.Video(slip_event), table.Video)...
                        & strcmp(table.Folder(slip_event), table.Folder)); %see if there are more events in same video
                    
                else %if we have an open video without grabbing events
                    vidname = app.video_name; vidname = char(vidname); csv_loc = app.filepath;
                    vidnum = str2double(vidname(3:end)); vidcam = vidname(1); %calculate variables for finding the video spot in table
                    tabmat = cell2mat(table.Video); tabnum = str2num(tabmat(:,3:end));  %#ok<ST2NM>
                    
                    row_start = find(tabnum >= vidnum & strcmp(csv_loc, table.Folder) & table.Camera == 1,1); %find index matching A_*
                    row_start = [row_start; find(tabnum >= vidnum & strcmp(csv_loc, table.Folder) & table.Camera == 2,1)]; %find index matching B_*
                    
                    if ~isempty(row_start) %if we can't find any grabbing event in the session, move to next grab event in the big table
                        
                        if strcmp(vidcam, 'A') && length(row_start) == 2 %if our video is A_* and row_start has two variables
                            row_start = min(row_start(1), row_start(2)); %choose the smaller of two variables
                        elseif strcmp(vidcam, 'B') && length(row_start) == 2 %if our video is B_*
                            row_start = max(row_start(1), row_start(2)); %choose the bigger
                        end %why? because if A, we need the closer parameter, and if B, we need the farther parameter; filepath sets the upper limit
                        
                        slip_event = row_start + find(table.Grab(row_start+1:end) == 2,1); %find first next grab event
                        slip_events = [slip_event; slip_event + find(table.Grab(slip_event+1:end) == 2 &... %find all events for this video
                            strcmp(table.Video(slip_event), table.Video(slip_event+1:end)) &...
                            strcmp(table.Folder(slip_event), table.Folder(slip_event+1:end)))];
                        
                    else
                        row_start = find(strcmp(app.filepath, table.Folder),1,'last'); %go to last video from this dataset in big table
                        slip_event = row_start + find(table.Grab(row_start+1:end) == 2,1); %and then search for grabs
                        slip_events = [slip_event; slip_event + find(table.Grab(slip_event+1:end) == 2 &...
                            strcmp(table.Video(slip_event), table.Video(slip_event+1:end)) &...
                            strcmp(table.Folder(slip_event), table.Folder(slip_event+1:end)))];
                    end
                end
                
            else %search for new event
                slip_event = slip_event + find(table.Grab(slip_event+1:end) == 2,1);
                
                if ~ismember(slip_event, slip_events) %if slip_event does not exist within slip_events, create new list
                    slip_events = [slip_event; slip_event + find(table.Grab(slip_event+1:end) == 2 &...
                        strcmp(table.Video(slip_event), table.Video(slip_event+1:end)) &...
                        strcmp(table.Folder(slip_event), table.Folder(slip_event+1:end)))];
                end
            end
            
            if isempty(slip_event), uialert(app.UIFigure,'There are no slipping events remaining!', 'Warning'); return; end %if last event
            
            %find reaches based on grab_events
            app.current_event = slip_event;
            app.all_events = find(~isnan(table.Grab) & strcmp(table.Video(slip_event), table.Video) & strcmp(table.Folder(slip_event), table.Folder));
            
            %update global variables
            app.slipping_event = slip_event; app.slipping_events = slip_events;
            if strcmp(app.filepath, table.Folder(slip_event)) && strcmp(app.video_name, table.Video(slip_event))...
                    && app.video_start == 1 %if video_start ~= 1, video is chopped due to large size, load next segment
                app.change_video = 0; %if same video, do not load again later
            else
                app.filepath = table.Folder(slip_event); app.video_name = table.Video(slip_event);
                app.change_video = 1; %if new video, load anew
            end
            
            %load video
            LoadVideo(app)
            app.change_video = 1; %baseline value
            
            %adjust slider (timeline)
            AdjustSlider(app)
            
            %display table values
            DrawTable(app);
            
            %load csv table of the video
            [app, csv_file] = LoadCsv(app, table, slip_event);
            
            %plot trajectories
            PlotTrajectory(app, csv_file)
            
            %plot pellets
            app = InitiatePellets(app);
            PelletStatus(app)
            
            %turn savecheck on
            app.savecheck = 0; %if 0, prompt before exiting the program
        end

        % Button pushed function: PreviousSlipButton
        function PreviousSlipButtonPushed(app, event)
            %adapted from PreviousEvent
            
            if  isempty(app.table_path)
                uialert(app.UIFigure,'Please load the table first!', 'Warning');
                return;
            end
            
            %create local values
            table = app.big_table; slip_event = app.slipping_event; slip_events = app.slipping_events;
            
            %create a list of grabbing events
            if isempty(slip_event)
                
                if isempty(app.video_name)
                    uialert(app.UIFigure,'No previous slipping events!', 'Warning'); return;
                    
                else
                    vidname = app.video_name; vidname = char(vidname); csv_loc = app.filepath;
                    vidnum = str2double(vidname(3:end)); vidcam = vidname(1); %calculate variables for finding the video spot in table
                    tabmat = cell2mat(table.Video); tabnum = str2num(tabmat(:,3:end));  %#ok<ST2NM>
                    
                    row_start = find(tabnum <= vidnum & strcmp(csv_loc, table.Folder) & table.Camera == 1,1,'last');
                    row_start = [row_start; find(tabnum <= vidnum & strcmp(csv_loc, table.Folder) & table.Camera == 2,1,'last')];
                    
                    if ~isempty(row_start)
                        
                        if strcmp(vidcam, 'A') && length(row_start) == 2
                            row_start = min(row_start(1), row_start(2));
                        elseif strcmp(vidcam, 'B') && length(row_start) == 2
                            row_start = max(row_start(1), row_start(2));
                        end %same thing applies as in nextbutton
                        
                        slip_event = find(table.Grab(1:row_start) == 2,1,'last'); %consider only grab events from current video backwards
                        slip_events = [find(table.Grab(1:slip_event-1) == 2 &...
                            strcmp(table.Video(slip_event), table.Video(1:slip_event-1)) &...
                            strcmp(table.Folder(slip_event), table.Folder(1:slip_event-1))); slip_event];
                    else
                        row_start = find(strcmp(app.filepath, table.Folder),1);
                        slip_event = find(table.Grab(1:row_start-1) == 2,1,'last');
                        slip_events = [find(table.Grab(1:slip_event-1) == 2 &...
                            strcmp(table.Video(slip_event), table.Video(1:slip_event-1)) &...
                            strcmp(table.Folder(slip_event), table.Folder(1:slip_event-1))); slip_event];
                    end
                end
                
            else
                slip_event = find(table.Grab(1:slip_event-1) == 2,1,'last');
                
                if ~ismember(slip_event, slip_events) %if slip_event does not exist within slip_events, create new list
                    slip_events = [find(table.Grab(1:slip_event-1) == 2 &...
                        strcmp(table.Video(slip_event), table.Video(1:slip_event-1)) &...
                        strcmp(table.Folder(slip_event), table.Folder(1:slip_event-1))); slip_event];
                end
            end
            
            if isempty(slip_event), uialert(app.UIFigure,'No previous grabbing events!', 'Warning'); return; end
            
            %find reaches based on slip_events
            app.current_event = slip_event;
            app.all_events = find(~isnan(table.Grab) & strcmp(table.Video(slip_event), table.Video) & strcmp(table.Folder(slip_event), table.Folder));
            
            %update global variables
            app.slipping_event = slip_event; app.slipping_events = slip_events;
            if strcmp(app.filepath, table.Folder(slip_event)) && strcmp(app.video_name, table.Video(slip_event))...
                    && app.video_start == 1
                app.change_video = 0; %if same video, do not load again later
            else
                app.filepath = table.Folder(slip_event); app.video_name = table.Video(slip_event);
                app.change_video = 1; %if new video, load anew
            end
            
            %load video
            LoadVideo(app)
            app.change_video = 1; %baseline value
            
            %adjust slider (timeline)
            AdjustSlider(app)
            
            %display table values
            DrawTable(app);
            
            %load csv table of the video
            [app, csv_file] = LoadCsv(app, table, slip_event);
            
            %plot trajectories
            PlotTrajectory(app, csv_file)
            
            %plot pellets
            app = InitiatePellets(app);
            PelletStatus(app)
            
            %turn savecheck on
            app.savecheck = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [0 640];
            app.UIAxes.YLim = [0 480];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.NextEventButton = uibutton(app.UIFigure, 'push');
            app.NextEventButton.ButtonPushedFcn = createCallbackFcn(app, @NextEventButtonPushed, true);
            app.NextEventButton.Text = 'Next Event';
            app.PreviousEventButton = uibutton(app.UIFigure, 'push');
            app.PreviousEventButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousEventButtonPushed, true);
            app.PreviousEventButton.Text = 'Previous Event';
            app.Slider = uislider(app.UIFigure);
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {''};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.SliderButtonRev = uibutton(app.UIFigure, 'push');
            app.SliderButtonRev.ButtonPushedFcn = createCallbackFcn(app, @SliderButtonRevPushed, true);
            app.SliderButtonRev.Text = '<';
            app.SliderButtonFw = uibutton(app.UIFigure, 'push');
            app.SliderButtonFw.ButtonPushedFcn = createCallbackFcn(app, @SliderButtonFwPushed, true);
            app.SliderButtonFw.Text = '>';
            app.YesButton_Success = uibutton(app.UIFigure, 'push');
            app.YesButton_Success.ButtonPushedFcn = createCallbackFcn(app, @YesButton_SuccessPushed, true);
            app.YesButton_Success.Text = 'Yes';
            app.NoButton_Success = uibutton(app.UIFigure, 'push');
            app.NoButton_Success.ButtonPushedFcn = createCallbackFcn(app, @NoButton_SuccessPushed, true);
            app.NoButton_Success.Text = 'No';
            app.SuccessLabel = uilabel(app.UIFigure);
            app.SuccessLabel.Text = 'Success?';
            app.GrabbingEventsLabel = uilabel(app.UIFigure);
            app.GrabbingEventsLabel.Text = 'Grabbing Events';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Video'; 'Box'; 'Frame'; 'Pellet'; 'Grab'; 'Success'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.CopyrightLabel = uilabel(app.UIFigure);
            app.CopyrightLabel.Text = 'Copyright AG Translational Neuromodulation 2022';
            app.SaveTableButton = uibutton(app.UIFigure, 'push');
            app.SaveTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTableButtonPushed, true);
            app.SaveTableButton.Text = 'Save Table';
            app.FramesLabel = uilabel(app.UIFigure);
            app.FramesLabel.Text = 'Frames';
            app.MouseReachAppLabel = uilabel(app.UIFigure);
            app.MouseReachAppLabel.Text = 'Mouse Reach App';
            app.CurrentEventLabel = uilabel(app.UIFigure);
            app.CurrentEventLabel.Text = 'Current Event';
            app.TableLabel = uilabel(app.UIFigure);
            app.TableLabel.Text = 'Table';
            app.LoadTableButton = uibutton(app.UIFigure, 'push');
            app.LoadTableButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTableButtonPushed, true);
            app.LoadTableButton.Text = 'Load Table';
            app.DisplayTrajectoriesButtonGroup = uibuttongroup(app.UIFigure);
            app.DisplayTrajectoriesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrajectoriesButtonSelectionChanged, true);
            app.DisplayTrajectoriesButtonGroup.Title = 'Display Trajectories';
            app.ShowButton = uitogglebutton(app.DisplayTrajectoriesButtonGroup);
            app.ShowButton.Text = 'Show';
            app.ShowButton.Value = true;
            app.HideButton = uitogglebutton(app.DisplayTrajectoriesButtonGroup);
            app.HideButton.Text = 'Hide';
            app.RemainingEventsButton = uibutton(app.UIFigure, 'push');
            app.RemainingEventsButton.ButtonPushedFcn = createCallbackFcn(app, @RemainingEventsButtonPushed, true);
            app.RemainingEventsButton.Text = 'Remaining Events';
            app.NextVideoButton = uibutton(app.UIFigure, 'push');
            app.NextVideoButton.ButtonPushedFcn = createCallbackFcn(app, @NextVideoButtonPushed, true);
            app.NextVideoButton.Text = 'Next Video';
            app.PreviousVideoButton = uibutton(app.UIFigure, 'push');
            app.PreviousVideoButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousVideoButtonPushed, true);
            app.PreviousVideoButton.Text = 'Previous Video';
            app.VideosLabel = uilabel(app.UIFigure);
            app.VideosLabel.Text = 'Videos';
            app.RemoveEventButton = uibutton(app.UIFigure, 'push');
            app.RemoveEventButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveEventButtonPushed, true);
            app.RemoveEventButton.Text = 'Remove Event';
            app.DisplayPelletsButtonGroup = uibuttongroup(app.UIFigure);
            app.DisplayPelletsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayPelletsButtonSelectionChanged, true);
            app.DisplayPelletsButtonGroup.Title = '    Display Pellets';
            app.Box1Button = uitogglebutton(app.DisplayPelletsButtonGroup);
            app.Box1Button.Text = 'Box 1';
            app.Box2Button = uitogglebutton(app.DisplayPelletsButtonGroup);
            app.Box2Button.Text = 'Box 2';
            app.Box3Button = uitogglebutton(app.DisplayPelletsButtonGroup);
            app.Box3Button.Text = 'Box 3';
            app.Box4Button = uitogglebutton(app.DisplayPelletsButtonGroup);
            app.Box4Button.Text = 'Box 4';
            app.AutomaticButton = uitogglebutton(app.DisplayPelletsButtonGroup);
            app.AutomaticButton.Text = 'Automatic';
            app.AutomaticButton.Value = true;
            app.AddEventButton = uibutton(app.UIFigure, 'state');
            app.AddEventButton.ValueChangedFcn = createCallbackFcn(app, @AddEventButtonValueChanged, true);
            app.AddEventButton.Text = 'Add Event';
            app.VideoEditFieldLabel = uilabel(app.UIFigure);
            app.VideoEditFieldLabel.Text = 'Video';
            app.VideoEditField = uieditfield(app.UIFigure, 'text');
            app.VideoEditField.Editable = 'off';
            app.GlobalTimeEditFieldLabel = uilabel(app.UIFigure);
            app.GlobalTimeEditFieldLabel.Text = 'Global Time';
            app.GlobalTimeEditField = uieditfield(app.UIFigure, 'text');
            app.GlobalTimeEditField.Editable = 'off';
            app.GrabLabel = uilabel(app.UIFigure);
            app.GrabLabel.Text = 'Grab?';
            app.YesButton_Grab = uibutton(app.UIFigure, 'push');
            app.YesButton_Grab.ButtonPushedFcn = createCallbackFcn(app, @YesButton_GrabPushed, true);
            app.YesButton_Grab.Text = 'Yes';
            app.NoButton_Grab = uibutton(app.UIFigure, 'push');
            app.NoButton_Grab.ButtonPushedFcn = createCallbackFcn(app, @NoButton_GrabPushed, true);
            app.NoButton_Grab.Text = 'No';
            app.TongueButton = uibutton(app.UIFigure, 'push');
            app.TongueButton.ButtonPushedFcn = createCallbackFcn(app, @TonguePushed, true);
            app.TongueButton.Text = 'Tongue';
            app.SlipButton = uibutton(app.UIFigure, 'push');
            app.SlipButton.ButtonPushedFcn = createCallbackFcn(app, @SlipPushed, true);
            app.SlipButton.Text = 'Slip';
            app.SlipsLabel = uilabel(app.UIFigure);
            app.SlipsLabel.Text = 'Slips';
            app.NextSlipButton = uibutton(app.UIFigure, 'push');
            app.NextSlipButton.ButtonPushedFcn = createCallbackFcn(app, @NextSlipButtonPushed, true);
            app.NextSlipButton.Text = 'Next Slip';
            app.PreviousSlipButton = uibutton(app.UIFigure, 'push');
            app.PreviousSlipButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousSlipButtonPushed, true);
            app.PreviousSlipButton.Text = 'Previous Slip';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mouse_reach

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end